classdef BackPropagationGuiApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        BackPropagationLabel        matlab.ui.control.Label
        SizeofthenetotorkEditFieldLabel  matlab.ui.control.Label
        SizeofthenetotorkEditField  matlab.ui.control.EditField
        DatafileEditFieldLabel      matlab.ui.control.Label
        DatafileEditField           matlab.ui.control.EditField
        ActivationFunctionDropDownLabel  matlab.ui.control.Label
        ActivationFunctionDropDown  matlab.ui.control.DropDown
        PercentagetraningEditFieldLabel  matlab.ui.control.Label
        PercentagetraningEditField  matlab.ui.control.NumericEditField
        TrainButton                 matlab.ui.control.Button
        TestButton                  matlab.ui.control.Button
        DataFormatDropDownLabel     matlab.ui.control.Label
        DataFormatDropDown          matlab.ui.control.DropDown
        EpochEditFieldLabel         matlab.ui.control.Label
        EpochEditField              matlab.ui.control.NumericEditField
        MSEEEditFieldLabel          matlab.ui.control.Label
        MSEEEditField               matlab.ui.control.NumericEditField
        Image                       matlab.ui.control.Image
        Image2                      matlab.ui.control.Image
    end

    
    properties (Access = public)
        Property % Description
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
            Number_of_neurons=eval(app.SizeofthenetotorkEditField.Value);
            num_Epoch=app.EpochEditField.Value;
            mse_thres=app.MSEEEditField.Value;
            filename=app.DatafileEditField.Value;
            train_info.training_percentage=app.PercentagetraningEditField.Value;
            dataFormat=app.DataFormatDropDown.Value;
            Ptrainning=app.PercentagetraningEditField.Value;
            backPropagation
            save('bp');
            
        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            load('bp') ;
            FeedForward
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.BackPropagationLabel = uilabel(app.UIFigure);
            app.BackPropagationLabel.Text = 'Back Propagation ';
            app.SizeofthenetotorkEditFieldLabel = uilabel(app.UIFigure);
            app.SizeofthenetotorkEditFieldLabel.Text = 'Size of the netotork ';
            app.SizeofthenetotorkEditField = uieditfield(app.UIFigure, 'text');
            app.SizeofthenetotorkEditField.Value = '[2,20,1]';
            app.DatafileEditFieldLabel = uilabel(app.UIFigure);
            app.DatafileEditFieldLabel.Text = 'Data file';
            app.DatafileEditField = uieditfield(app.UIFigure, 'text');
            app.DatafileEditField.Value = 'doubleMoondata.xlsx';
            app.ActivationFunctionDropDownLabel = uilabel(app.UIFigure);
            app.ActivationFunctionDropDownLabel.Text = 'Activation Function';
            app.ActivationFunctionDropDown = uidropdown(app.UIFigure);
            app.ActivationFunctionDropDown.Items = {'Hiporrbolic tangent ', 'Bipolar Sigmoid'};
            app.ActivationFunctionDropDown.Value = 'Hiporrbolic tangent ';
            app.PercentagetraningEditFieldLabel = uilabel(app.UIFigure);
            app.PercentagetraningEditFieldLabel.Text = 'Percentage traning';
            app.PercentagetraningEditField = uieditfield(app.UIFigure, 'numeric');
            app.PercentagetraningEditField.Limits = [0 100];
            app.PercentagetraningEditField.Value = 0.7;
            app.TrainButton = uibutton(app.UIFigure, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Text = 'Train';
            app.TestButton = uibutton(app.UIFigure, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            app.DataFormatDropDownLabel = uilabel(app.UIFigure);
            app.DataFormatDropDownLabel.Text = 'Data Format';
            app.DataFormatDropDown = uidropdown(app.UIFigure);
            app.DataFormatDropDown.Items = {'Row', 'Column'};
            app.DataFormatDropDown.Value = 'Row';
            app.EpochEditFieldLabel = uilabel(app.UIFigure);
            app.EpochEditFieldLabel.Text = 'Epoch';
            app.EpochEditField = uieditfield(app.UIFigure, 'numeric');
            app.EpochEditField.Value = 500;
            app.MSEEEditFieldLabel = uilabel(app.UIFigure);
            app.MSEEEditFieldLabel.Text = 'MSEE';
            app.MSEEEditField = uieditfield(app.UIFigure, 'numeric');
            app.MSEEEditField.Value = 0.001;
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'backpropagation_neural_network-512.png';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'primary_logotype1.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BackPropagationGuiApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end