classdef SVM < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SupportVectorMachineSVMLabel   matlab.ui.control.Label
        CvalueEditFieldLabel           matlab.ui.control.Label
        CvalueEditField                matlab.ui.control.NumericEditField
        SigmaforRBFEditFieldLabel      matlab.ui.control.Label
        SigmaforRBFEditField           matlab.ui.control.NumericEditField
        ThresholdforSupportVectorDetectionLabel  matlab.ui.control.Label
        ThresholdforSupportVectorDetectionEditField  matlab.ui.control.NumericEditField
        InputdataEditFieldLabel        matlab.ui.control.Label
        InputdataEditField             matlab.ui.control.EditField
        DataFormatDropDownLabel        matlab.ui.control.Label
        DataFormatDropDown             matlab.ui.control.DropDown
        TestButton                     matlab.ui.control.Button
        TrainButton                    matlab.ui.control.Button
        PercentagetrainingEditField_3Label  matlab.ui.control.Label
        PercentagetrainingEditField_3  matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: UIFigure
        function UIFigureButtonDown(app, event)
            
        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
            C=app.CvalueEditField.Value;
            p1 = app.SigmaforRBFEditField.Value;    % sigma for RBF, width
            epsilon = app.ThresholdforSupportVectorDetectionEditField.Value; % threshold for Support Vector Detection
            filename=app.InputdataEditField.Value;
            dataFormat=app.DataFormatDropDown.Value;
            Ptrainning=app.PercentagetrainingEditField_3.Value;
            supportVectorMachine
            save('SVM_T');
        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            load('SVM_T') ;
            SVMtrain
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.SupportVectorMachineSVMLabel = uilabel(app.UIFigure);
            app.SupportVectorMachineSVMLabel.Text = {'Support Vector Machine'; 'SVM'};
            app.CvalueEditFieldLabel = uilabel(app.UIFigure);
            app.CvalueEditFieldLabel.Text = 'C value';
            app.CvalueEditField = uieditfield(app.UIFigure, 'numeric');
            app.CvalueEditField.Value = 50;
            app.SigmaforRBFEditFieldLabel = uilabel(app.UIFigure);
            app.SigmaforRBFEditFieldLabel.Text = 'Sigma for RBF ';
            app.SigmaforRBFEditField = uieditfield(app.UIFigure, 'numeric');
            app.SigmaforRBFEditField.Value = 1;
            app.ThresholdforSupportVectorDetectionLabel = uilabel(app.UIFigure);
            app.ThresholdforSupportVectorDetectionLabel.Text = {' Threshold for Support'; ' Vector Detection'};
            app.ThresholdforSupportVectorDetectionEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThresholdforSupportVectorDetectionEditField.Value = 1e-05;
            app.InputdataEditFieldLabel = uilabel(app.UIFigure);
            app.InputdataEditFieldLabel.Text = 'Input data ';
            app.InputdataEditField = uieditfield(app.UIFigure, 'text');
            app.InputdataEditField.Value = 'doubleMoondata.xlsx';
            app.DataFormatDropDownLabel = uilabel(app.UIFigure);
            app.DataFormatDropDownLabel.Text = 'Data Format';
            app.DataFormatDropDown = uidropdown(app.UIFigure);
            app.DataFormatDropDown.Items = {'Row', 'Column'};
            app.DataFormatDropDown.Value = 'Row';
            app.TestButton = uibutton(app.UIFigure, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            app.TrainButton = uibutton(app.UIFigure, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Text = 'Train';
            app.PercentagetrainingEditField_3Label = uilabel(app.UIFigure);
            app.PercentagetrainingEditField_3Label.Text = 'Percentage training ';
            app.PercentagetrainingEditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.PercentagetrainingEditField_3.Value = 50;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SVM

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end