classdef SomGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        NumberofepochsLabel            matlab.ui.control.Label
        NumberofepochsEditField        matlab.ui.control.NumericEditField
        LearningrateLabel              matlab.ui.control.Label
        LearningrateEditField          matlab.ui.control.NumericEditField
        SigmaEditFieldLabel            matlab.ui.control.Label
        SigmaEditField                 matlab.ui.control.NumericEditField
        NumberofNeuronsEditFieldLabel  matlab.ui.control.Label
        NumberofNeuronsEditField       matlab.ui.control.NumericEditField
        NumberofDimentionsEditFieldLabel  matlab.ui.control.Label
        NumberofDimentionsEditField    matlab.ui.control.NumericEditField
        SelfOrganizingMapLabel         matlab.ui.control.Label
        TrainButton                    matlab.ui.control.Button
        Image                          matlab.ui.control.Image
        InputdataEditFieldLabel        matlab.ui.control.Label
        InputdataEditField             matlab.ui.control.EditField
        DataFormatDropDownLabel        matlab.ui.control.Label
        DataFormatDropDown             matlab.ui.control.DropDown
        NumberofVectorsEditFieldLabel  matlab.ui.control.Label
        NumberofVectorsEditField       matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
            Number_Of_Dimentions=app.NumberofDimentionsEditField.Value;
            Number_of_neurons=app.NumberofNeuronsEditField.Value;
            Number_of_epochs=app.NumberofepochsEditField.Value;
            Number_of_Vectors=app.NumberofVectorsEditField.Value;
            learning_rate=app.LearningrateEditField.Value;
            
            Sigma=app.SigmaEditField.Value;
            data_Format=app.DataFormatDropDown.Value;
            Inputdata=app.InputdataEditField.Value;
            SelfOrganazingMap
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.NumberofepochsLabel = uilabel(app.UIFigure);
            app.NumberofepochsLabel.Text = 'Number of epochs';
            app.NumberofepochsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofepochsEditField.Value = 10;
            app.LearningrateLabel = uilabel(app.UIFigure);
            app.LearningrateLabel.Text = '  Learning rate ';
            app.LearningrateEditField = uieditfield(app.UIFigure, 'numeric');
            app.LearningrateEditField.Value = 0.1;
            app.SigmaEditFieldLabel = uilabel(app.UIFigure);
            app.SigmaEditFieldLabel.Text = ' Sigma';
            app.SigmaEditField = uieditfield(app.UIFigure, 'numeric');
            app.SigmaEditField.Value = 10;
            app.NumberofNeuronsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofNeuronsEditFieldLabel.Text = 'Number of Neurons';
            app.NumberofNeuronsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofNeuronsEditField.Value = 20;
            app.NumberofDimentionsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofDimentionsEditFieldLabel.Text = 'Number of Dimentions ';
            app.NumberofDimentionsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofDimentionsEditField.Value = 2;
            app.SelfOrganizingMapLabel = uilabel(app.UIFigure);
            app.SelfOrganizingMapLabel.Text = 'Self Organizing Map';
            app.TrainButton = uibutton(app.UIFigure, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Text = 'Train';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'primary_logotype1.png';
            app.InputdataEditFieldLabel = uilabel(app.UIFigure);
            app.InputdataEditFieldLabel.Text = 'Input data ';
            app.InputdataEditField = uieditfield(app.UIFigure, 'text');
            app.InputdataEditField.Value = 'XORdata.xlsx';
            app.DataFormatDropDownLabel = uilabel(app.UIFigure);
            app.DataFormatDropDownLabel.Text = 'Data Format';
            app.DataFormatDropDown = uidropdown(app.UIFigure);
            app.DataFormatDropDown.Items = {'Row', 'Column'};
            app.DataFormatDropDown.Value = 'Row';
            app.NumberofVectorsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofVectorsEditFieldLabel.Text = 'Number of Vectors';
            app.NumberofVectorsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofVectorsEditField.Value = 80;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SomGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end